classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Soal1Tab           matlab.ui.container.Tab
        Image              matlab.ui.control.Image
        srcEditField       matlab.ui.control.EditField
        srcEditFieldLabel  matlab.ui.control.Label
        PilihGambarButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        Soal2Tab           matlab.ui.container.Tab
        Soal3Tab           matlab.ui.container.Tab
        Soal4Tab           matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function plot_histogram(~, ax, binLocations, counts)
            % PLOTHISTOGRAM Display a histogram using a bar plot in the specified axes
            %   plotHistogram(ax, binLocations, counts) creates a bar plot of the 
            %   histogram
        
            % Clear previous plots in the axes
            cla(ax);
        
            % Use bar plot to display the histogram in the specified axes
            b = bar(ax, binLocations, counts); 
        
            if (size(counts,1) == 3)
                b(1).FaceColor = [1, 0, 0];
                b(2).FaceColor = [0, 1, 0];
                b(3).FaceColor = [0, 0, 1];
            end
            xlabel(ax,'Pixel Intensity');
            ylabel(ax, 'Count');
            title(ax, 'Image Histogram');
        end

        function [img, fullPath] = browseImage(app)
            [file, path] = uigetfile({"*.png"; "*.jpg"}, 'Pilih Gambar');
            
            if isequal(file, 0)  % User clicked cancel
                img = [];        % Return empty image
                fullPath = '';   % Return empty path
            else
                fullPath = fullfile(path, file);  % Concatenate path and file
                img = imread(fullPath);            % Read the image file
            end

            drawnow;
            figure(app.UIFigure)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField.Value = fullPath;
            app.Image.ImageSource = fullPath;
            [counts, binLocations] = image_histogram(img);
            plot_histogram(app, app.UIAxes, binLocations, counts);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Soal1Tab = uitab(app.TabGroup);
            app.Soal1Tab.Title = 'Soal 1';
            app.UIAxes = uiaxes(app.Soal1Tab);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PilihGambarButton = uibutton(app.Soal1Tab, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.srcEditFieldLabel = uilabel(app.Soal1Tab);
            app.srcEditFieldLabel.Text = 'src';
            app.srcEditField = uieditfield(app.Soal1Tab, 'text');
            app.srcEditField.Editable = 'off';
            app.Image = uiimage(app.Soal1Tab);
            app.Soal2Tab = uitab(app.TabGroup);
            app.Soal2Tab.Title = 'Soal 2';
            app.Soal3Tab = uitab(app.TabGroup);
            app.Soal3Tab.Title = 'Soal 3';
            app.Soal4Tab = uitab(app.TabGroup);
            app.Soal4Tab.Title = 'Soal 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end